classdef Fovea_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OpenfolderButton      matlab.ui.control.Button
        UnknownButton         matlab.ui.control.Button
        Y_Spinner             matlab.ui.control.Spinner
        YsliderLabel          matlab.ui.control.Label
        DoneButton            matlab.ui.control.Button
        GraderDropDown        matlab.ui.control.DropDown
        GraderDropDownLabel   matlab.ui.control.Label
        X_slider              matlab.ui.control.Slider
        XsliderSliderLabel    matlab.ui.control.Label
        ScanDropDown          matlab.ui.control.DropDown
        ScanDropDownLabel     matlab.ui.control.Label
        EyeDropDown           matlab.ui.control.DropDown
        EyeDropDownLabel      matlab.ui.control.Label
        PatientDropDown       matlab.ui.control.DropDown
        PatientDropDownLabel  matlab.ui.control.Label
        SelectButton          matlab.ui.control.Button
        bscan                 matlab.ui.control.UIAxes
        Th_map                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DB_table=table; % Table of data from DB 
        results_table=table; % Table of data with grader's results
        analysis_table = table; % Table of scans left for analysis
        vg_bscn_table=table;
        vg_bscn_vsr_table=table;
        data_path='';
        DB_csv='';
        aup_path ='';
        Results_csv='';
        fovea_y;
        fovea_x;
        y_vec;
        pt='';
        eye='';
        scan='';
        aup;
        grader='';
        main_path='';
        results_path='';
        results_columns='';
    end
    
    methods (Access = private)
        
        function plot_th_map(app,aup_path,fovea_x,fovea_y)
            map = readmatrix([aup_path '\DB_Data\VG_Thickness_Map.csv']);
            [ThickMap_min,ThickMap_max]=get_int_range_func(app,map,660);
            xgrid = -1500:10:1500;
            ygrid = -1500:10:1500;
            cla(app.Th_map,'reset')
            imagesc(app.Th_map,xgrid,ygrid,map,[ThickMap_min ThickMap_max]);
            hold(app.Th_map);
            colormap(app.Th_map,'jet');
            colorbar(app.Th_map);set(app.Th_map,'YDir','normal');
            xlabel(app.Th_map,'x (um)');ylabel(app.Th_map,'y (um)'); pbaspect(app.Th_map,[1 1 1]);c = colorbar(app.Th_map);c.Label.String = 'um';
            set(app.Th_map,'xtick',[],'ytick',[]);
            line(app.Th_map,[-1500,1500],[fovea_y,fovea_y],'Color','blue','LineStyle','--')
            line(app.Th_map,[fovea_x,fovea_x],[-1500,1500],'Color','blue','LineStyle','--')
            %plot_etdrs(app,0,0)
        end
        
        function [ThickMap_min,ThickMap_max]=get_int_range_func(app,ThickMap,ind1)
        
        Thick_sort=sort(ThickMap(:));
        Thick_sort(isnan(Thick_sort))=[];
        ind1=min(ind1,ceil(0.02*length(Thick_sort)));
        if length(Thick_sort)<=2
            ThickMap_min=nan;
            ThickMap_max=nan;
        else
            ThickMap_min=round(Thick_sort(ind1));
            ThickMap_max=round(Thick_sort(end-ind1));
        end
        
        ThickMap_max=min(ThickMap_max,700);
        ThickMap_min=max(ThickMap_min,100);
        end 
        
        function plot_etdrs(app,CenterX,CenterY)
        % [CenterX_norm,CenterY_norm] = GetFigNormCoord(CenterX,CenterY);
        R1=1000/2; %um
        R2=3000/2;%um
        ang_vec=0:359;
        ang_vec=deg2rad(ang_vec);
        x_vec1=R1*cos(ang_vec);
        x_vec1 = x_vec1+CenterX;
        y_vec1=R1*sin(ang_vec);
        y_vec1 = y_vec1+CenterY;
        hold(app.Th_map);plot(app.Th_map,x_vec1,y_vec1,'g','lineWidth',1);
        
        x_vec2=R2*cos(ang_vec);
        x_vec2 = x_vec2+CenterX;
        y_vec2=R2*sin(ang_vec);
        y_vec2 = y_vec2+CenterY;
        ang_vec2=[45,135,225,315];
        plot(app.Th_map,x_vec2,y_vec2,'g','lineWidth',1);
        
        for l=1:4
        ang=ang_vec2(l)+1;
        plot(app.Th_map,[x_vec1(ang) x_vec2(ang)],[y_vec1(ang) y_vec2(ang)],'g','lineWidth',1);
        end
        end

        function ind_min = display_bscan(app,aup_path,fovea_x,fovea_y)

        app.vg_bscn_table = readtable([aup_path '\DB_Data\VG_Bscan.csv']);
        app.vg_bscn_vsr_table = readtable([aup_path '\DB_Data\VG_Bscan_VSR.csv']);
        b_reg_classes = app.vg_bscn_table(app.vg_bscn_vsr_table.RastIndex,:).ClassTypeReg;
        relevant_b = find(b_reg_classes~=3);
        app.y_vec = app.vg_bscn_vsr_table.YRegSort(relevant_b);
        [~,ind_min] = min(abs(app.y_vec-fovea_y));
        b = relevant_b(ind_min(1));
        bscan_path = [aup_path '\Visualization\frame_' num2str(b) '.jpg'];    
        Bscan = imread(bscan_path);
        [r_fig,c_fig,~] = size(Bscan);
        
        title(app.bscan, []);
        xlabel(app.bscan, []);
        ylabel(app.bscan, []);
        imshow(Bscan,'Parent',app.bscan,'XData', [1 c_fig],'YData', [1 r_fig]);
        hold(app.bscan);
        x_pixels = c_fig;
        y_pixels = r_fig;
        x_position_b = x_pixels/3000*(fovea_x+1500);
        line(app.bscan,[x_position_b,x_position_b],[0 y_pixels],'Color','blue','LineStyle','--');
        end

        function results_table = read_results_table(app)
        try
           results_table = readtable([app.results_path '\' app.Results_csv]);
        catch
           results_table = array2table(zeros(0,length(app.results_columns)),'VariableNames',app.results_columns); %,'VariableTypes',varTypes
        end
        end

        function update_results_csv(app,fovea_x,fovea_y)
        if ~ismember(app.aup,app.results_table.Aup)
            aup_table = table({app.pt},{app.eye},{app.scan},app.aup,round(fovea_x),fovea_y,{app.grader},{datestr(datetime(now,'ConvertFrom','datenum'))},'VariableNames',app.results_columns); %,'VariableTypes',varTypes
            app.results_table = [app.results_table;aup_table];
            if ~exist(app.results_path);mkdir(app.results_path);end
            writetable(app.results_table,[app.results_path '\'  app.Results_csv]);
        end
        end

        function update_eye_list(app)
        app.pt = app.PatientDropDown.Value;
        pt_table = app.analysis_table(strcmp(app.pt,app.analysis_table.Patient),:);
        eyes = unique(pt_table.Eye);
        app.EyeDropDown.Items = eyes;
        if ~isempty(app.EyeDropDown.Items)
            app.EyeDropDown.Value = app.EyeDropDown.Items{1}; 
        end 
        end

        function update_scan_list(app)
        app.pt = app.PatientDropDown.Value;
        app.eye = app.EyeDropDown.Value;
        eye_table = app.analysis_table(strcmp(app.eye,app.analysis_table.Eye) & strcmp(app.pt,app.analysis_table.Patient),:);
        scans = unique(eye_table.Scan);
        app.ScanDropDown.Items = scans;
        if ~isempty(app.ScanDropDown.Items)
            app.ScanDropDown.Value = app.ScanDropDown.Items{1}; 
        end
        end
        
        function analysis_table = eval_analysis_table(app)
        done_aups = ismember(app.DB_table.Aup,app.results_table.Aup);
        analysis_table = app.DB_table;
        analysis_table(done_aups,:) = [];
        end
       
        function turn_off_thmap(app)
        cla(app.Th_map);
        colorbar(app.Th_map,'off');
        xlabel(app.Th_map,[]);ylabel(app.Th_map,[]);
        set(app.Th_map,'XColor', 'none','YColor','none');
        app.Th_map.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             set(app.SelectButton, 'Enable', 'off');
             set(app.DoneButton, 'Enable', 'off');
             set(app.UnknownButton, 'Enable', 'off');
             app.GraderDropDown.Items = {'Natalie','Yael','Tomer','Eden','Yahav'};
             app.GraderDropDown.Value = app.GraderDropDown.Items{1};
             app.DB_csv = 'DB_fovea_data.csv';
             app.Results_csv = 'Graders_fovea_results.csv';
             app.results_columns = {'Patient','Eye','Scan','Aup','FoveaPositionX','FoveaPositionY','Grader','RecordInsertionTime'};

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
             app.scan = app.ScanDropDown.Value;
             app.grader = app.GraderDropDown.Value;
             scan_table = app.DB_table(strcmp(app.scan,app.DB_table.Scan),:);
             app.aup = scan_table.Aup;
             app.aup_path = [app.data_path '\' num2str(app.aup)]; 
             fovea_x_orig = scan_table.FoveaPositionX;
             app.fovea_x = fovea_x_orig;
             fovea_y_orig = scan_table.FoveaPositionY;
             app.fovea_y = fovea_y_orig;
             
             app.X_slider.Value = app.fovea_x;

             ind_min = display_bscan(app,app.aup_path,app.fovea_x,app.fovea_y);
             app.Y_Spinner.Limits = [1 length(app.y_vec)];
             app.Y_Spinner.Value = length(app.y_vec)+1-ind_min;

             plot_th_map(app,app.aup_path,app.fovea_x,app.fovea_y);
             set(app.DoneButton, 'Enable', 'on');
             set(app.UnknownButton, 'Enable', 'on');
        end

        % Value changed function: PatientDropDown
        function PatientDropDownValueChanged(app, event)
             set(app.DoneButton, 'Enable', 'off');
             set(app.UnknownButton, 'Enable', 'off');
             cla(app.bscan);
             turn_off_thmap(app);
             update_eye_list(app);
             update_scan_list(app);
        end

        % Value changed function: EyeDropDown
        function EyeDropDownValueChanged(app, event)
             set(app.DoneButton, 'Enable', 'off');
             set(app.UnknownButton, 'Enable', 'off');
             cla(app.bscan);
             turn_off_thmap(app);
             update_scan_list(app);
        end

        % Value changed function: X_slider
        function X_sliderValueChanged(app, event)
            app.fovea_x = app.X_slider.Value;
            display_bscan(app,app.aup_path,app.fovea_x,app.fovea_y);  
            plot_th_map(app,app.aup_path,app.fovea_x,app.fovea_y);
        end

        % Callback function
        function Y_sliderValueChanged(app, event)

        end

        % Value changed function: Y_Spinner
        function Y_SpinnerValueChanged(app, event)
            b_scan = length(app.y_vec)+1-app.Y_Spinner.Value;
            app.fovea_y = app.y_vec(b_scan);
            display_bscan(app,app.aup_path,app.fovea_x,app.fovea_y);  
            plot_th_map(app,app.aup_path,app.fovea_x,app.fovea_y);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            update_results_csv(app,app.fovea_x,app.fovea_y);
            cla(app.bscan);
            turn_off_thmap(app);
            app.analysis_table = eval_analysis_table(app);
            update_eye_list(app);
            update_scan_list(app);
            set(app.DoneButton, 'Enable', 'off');
            set(app.UnknownButton, 'Enable', 'off');
        end

        % Button pushed function: UnknownButton
        function UnknownButtonPushed(app, event)
            update_results_csv(app,nan,nan)
            cla(app.bscan);
            turn_off_thmap(app);
            app.analysis_table = eval_analysis_table(app);
            update_eye_list(app);
            update_scan_list(app);
            set(app.DoneButton, 'Enable', 'off');
            set(app.UnknownButton, 'Enable', 'off');
        end

        % Value changed function: ScanDropDown
        function ScanDropDownValueChanged(app, event)
             set(app.DoneButton, 'Enable', 'off');
             set(app.UnknownButton, 'Enable', 'off');
             set(app.SelectButton, 'Enable', 'on');   
             cla(app.bscan);
             turn_off_thmap(app);
        end

        % Button pushed function: OpenfolderButton
        function OpenfolderButtonPushed(app, event)
             app.main_path = uigetdir;
             set(app.SelectButton, 'Enable', 'on');
             app.data_path = [app.main_path '\Data'];
             app.results_path = [app.main_path '\Results'];
             app.results_table = read_results_table(app);
             app.DB_table = readtable([app.data_path '\' app.DB_csv]);
             app.analysis_table = eval_analysis_table(app);
             app.PatientDropDown.Items = unique(app.analysis_table.Patient); 
             if length(app.PatientDropDown.Items)>1
                 app.PatientDropDown.Value = app.PatientDropDown.Items{1};
                 update_eye_list(app);
                 update_scan_list(app);
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Th_map = uiaxes(app.UIFigure);
            app.bscan = uiaxes(app.UIFigure);
            xlabel(app.bscan, 'X')
            ylabel(app.bscan, 'Y')
            zlabel(app.bscan, 'Z')
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.PatientDropDownLabel = uilabel(app.UIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.UIFigure);
            app.PatientDropDown.Items = {};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = {};
            app.EyeDropDownLabel = uilabel(app.UIFigure);
            app.EyeDropDownLabel.Text = 'Eye';
            app.EyeDropDown = uidropdown(app.UIFigure);
            app.EyeDropDown.Items = {};
            app.EyeDropDown.ValueChangedFcn = createCallbackFcn(app, @EyeDropDownValueChanged, true);
            app.EyeDropDown.Value = {};
            app.ScanDropDownLabel = uilabel(app.UIFigure);
            app.ScanDropDownLabel.Text = 'Scan';
            app.ScanDropDown = uidropdown(app.UIFigure);
            app.ScanDropDown.Items = {};
            app.ScanDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanDropDownValueChanged, true);
            app.ScanDropDown.Value = {};
            app.XsliderSliderLabel = uilabel(app.UIFigure);
            app.XsliderSliderLabel.Text = 'X slider';
            app.X_slider = uislider(app.UIFigure);
            app.X_slider.Limits = [-1500 1500];
            app.X_slider.ValueChangedFcn = createCallbackFcn(app, @X_sliderValueChanged, true);
            app.GraderDropDownLabel = uilabel(app.UIFigure);
            app.GraderDropDownLabel.Text = 'Grader';
            app.GraderDropDown = uidropdown(app.UIFigure);
            app.GraderDropDown.Items = {};
            app.GraderDropDown.Value = {};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.YsliderLabel = uilabel(app.UIFigure);
            app.YsliderLabel.Text = 'Y slider';
            app.Y_Spinner = uispinner(app.UIFigure);
            app.Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @Y_SpinnerValueChanged, true);
            app.UnknownButton = uibutton(app.UIFigure, 'push');
            app.UnknownButton.ButtonPushedFcn = createCallbackFcn(app, @UnknownButtonPushed, true);
            app.UnknownButton.Text = 'Unknown';
            app.OpenfolderButton = uibutton(app.UIFigure, 'push');
            app.OpenfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfolderButtonPushed, true);
            app.OpenfolderButton.Text = 'Open folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fovea_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end